classdef controlpanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AccumulatorControlPanel       matlab.ui.container.Panel
        GetStatusButton               matlab.ui.control.Button
        SDCClosedEditField            matlab.ui.control.NumericEditField
        SDCClosedEditFieldLabel       matlab.ui.control.Label
        SDCClosedLamp                 matlab.ui.control.Lamp
        AMSOKEditField                matlab.ui.control.NumericEditField
        AMSOKEditFieldLabel           matlab.ui.control.Label
        AMSOKLamp                     matlab.ui.control.Lamp
        OffButton_2                   matlab.ui.control.Button
        OnButton_2                    matlab.ui.control.Button
        InitializeButton              matlab.ui.control.Button
        AccumulatorvalidationtoolV10Label  matlab.ui.control.Label
        ValidationPanel               matlab.ui.container.Panel
        StartSimButton                matlab.ui.control.Button
        StopSimButton                 matlab.ui.control.Button
        InitializeSimButton           matlab.ui.control.Button
        ImportDataButton              matlab.ui.control.Button
        BatterySimulatorControlPanel  matlab.ui.container.Panel
        GetStatusButton_2             matlab.ui.control.Button
        StatusEditField               matlab.ui.control.NumericEditField
        StatusEditFieldLabel          matlab.ui.control.Label
        StatusBSLamp                  matlab.ui.control.Lamp
        ResetButton                   matlab.ui.control.Button
        OffButton                     matlab.ui.control.Button
        OnButton                      matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        channel
        db
        msg_acc_busspeed
        msg_acc_ctrl               
        msg_acc_status
        msg_acc_status_filter
        msg_acc_status_AMS
        msg_acc_status_SDC
        msg_bs_ctrl 
        msg_bs_status
        msg_bs_status_filter
        msg_bs_status_current       
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Clear Workspace
            evalin('base', 'clear all')
            clc

            % Setup CAN Channel 
            app.db = canDatabase('validationtool.dbc');
            app.channel = canChannel('MathWorks', 'Virtual 1', 1);  
            app.channel.Database = app.db;
            configBusSpeed(app.channel, 250000);
            start(app.channel);            
            assignin('base','channel',app.channel); 
                             
            % Define CAN Messages for GUI Buttons 
            app.msg_acc_ctrl = canMessage(app.db, 'accu_ctrl');  
            app.msg_bs_ctrl = canMessage(app.db, 'bs_ctrl'); 
            
            % Set Lamp Colors    
            app.AMSOKLamp.Color = 'r';
            app.SDCClosedLamp.Color = 'r';
            app.StatusBSLamp.Color = 'r'; 
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            %Initialize accumulator
            transmitPeriodic(app.channel, app.msg_acc_ctrl , "On", 0.1); 
            app.msg_acc_ctrl.Data=[0 0 0 0 0 0 0 0];
            assignin('base','msg_acc_ctrl',app.msg_acc_ctrl);                     
        end

        % Button pushed function: OnButton_2
        function OnButton_2Pushed(app, event)
            %Start accumulator
            app.msg_acc_ctrl.Data=[0 128 0 0 0 0 0 0];
            assignin('base','msg_acc_ctrl',app.msg_acc_ctrl); 
        end

        % Button pushed function: OffButton_2
        function OffButton_2Pushed(app, event)
            %Stop accumulator
            app.msg_acc_ctrl.Data=[0 0 0 0 0 0 0 0];
            assignin('base','msg_acc_ctrl',app.msg_acc_ctrl);             
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            % Get and display accumulator status
            app.msg_acc_status = receive(app.channel, Inf, 'OutputFormat', 'timetable');            
            app.msg_acc_status_filter = groupfilter(app.msg_acc_status,'Time',@(x)  x == 577, 'ID');
            app.msg_acc_status_AMS = (app.msg_acc_status_filter.Signals{end}.AMS_OK);
            app.msg_acc_status_SDC = (app.msg_acc_status_filter.Signals{end}.SDC_closed); 

            app.AMSOKEditField.Value = app.msg_acc_status_AMS;

            if  app.AMSOKEditField.Value == -1
                 app.AMSOKEditField.Value=1;
                 app.AMSOKLamp.Color = 'g';
            else 
                 app.AMSOKEditField.Value=0;  
                 app.AMSOKLamp.Color = 'r';
            end
                   
            app.SDCClosedEditField.Value = app.msg_acc_status_SDC;   

            if  app.SDCClosedEditField.Value == -1
                 app.SDCClosedEditField.Value=1;
                 app.SDCClosedLamp.Color = 'g';
            else 
                 app.SDCClosedEditField.Value=0;  
                 app.SDCClosedLamp.Color = 'r';
            end           
        end

        % Button pushed function: OnButton
        function OnButtonPushed(app, event)
            %Start batterysimulator
            app.msg_bs_ctrl.Data=[2];                     
            assignin('base','msg_bs_ctrl', app.msg_bs_ctrl);
            transmit(app.channel, app.msg_bs_ctrl);            
        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            %Stop batterysimulator
            stop(app.channel)
            start(app.channel)
            app.msg_bs_ctrl.Data=[0];  
            assignin('base','msg_bs_ctrl', app.msg_bs_ctrl);
            transmit(app.channel, app.msg_bs_ctrl);     
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %Reset batterysimulator (needed when StatusBSLamp is orange)
            app.msg_bs_ctrl.Data=[1];  
            assignin('base','msg_bs_ctrl', app.msg_bs_ctrl);
            transmit(app.channel, app.msg_bs_ctrl);     
        end

        % Button pushed function: GetStatusButton_2
        function GetStatusButton_2Pushed(app, event)
            % Get and display batterysimulator status
            app.msg_bs_status = receive(app.channel, Inf, 'OutputFormat', 'timetable');
            app.msg_bs_status_filter=groupfilter(app.msg_bs_status,'Time',@(x)  x == 282, 'ID');
            app.msg_bs_status_current=app.msg_bs_status_filter.Signals{end}.status;

            app.StatusEditField.Value = app.msg_bs_status_current;

            if app.StatusEditField.Value == 6
                app.StatusBSLamp.Color = 'g';
            elseif app.StatusEditField.Value == 3
                app.StatusBSLamp.Color = 'r';
            else
                app.StatusBSLamp.Color = [0.93, 0.69, 0.13];                
            end
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            %% Import currentprofile 

            [Time, Distance, IDC, UDC] = importfile('210721_zenzenhof.csv', [4847, 10983]);
            
            % 75% Power
%           idc = transpose(IDC);
%           idc = transpose([idc idc idc]);
%           time75 = transpose(0:.1:1841.0);
%           signal = [time75, idc];

            % 50% Power
            IDC75single = transpose(IDC);
            IDC75 = [IDC75single IDC75single IDC75single];
            IDC75 = transpose(IDC75);
                       
            for i=1:length(IDC75single)
            IDC50single(i)=(0.5*IDC75single(i))/0.75;
            end

            IDC50=[IDC50single IDC50single IDC50single];
            IDC50=transpose(IDC50);          
                       
            IDC50=[IDC50single IDC50single IDC50single IDC50single];
            idc=transpose(IDC50);
            
            time50 = transpose(0:.1:2454.7);

            signal = [time50, idc];

            assignin('base','currentprofile', signal);

            clear Time
            clear Distance
            clear IDC
            clear UDC

            %% Import equivalent circuit data for SOC estimation
                                   
            load('estimatedparameters.mat');            
            
            SOC_LUT = (0.1:.1:1)'; 
                      
            % Lookup Table Breakpoints
            Battery.SOC_LUT = SOC_LUT;
            
            % Em open-circuit voltage
            Battery.Em_LUT = [estimatedparameters.Em];
            
            % Terminal Resistance Properties
            % R0 Resistance
            Battery.R0_LUT = [estimatedparameters.R0];
            
            % RC Properties
            % R1 Resistance
            Battery.R1_LUT = [estimatedparameters.R1];
            
            % R2 Resistance
            Battery.R2_LUT = [estimatedparameters.R2];
            
            % C1 Capacitance 
            Battery.C1_LUT = [estimatedparameters.C1];
            
            % C2 Capacitance
            Battery.C2_LUT = [estimatedparameters.C2];

            % UKF Sample time
            Ts=1;

            assignin('base','Battery',Battery);
            assignin('base','Ts',Ts);
            
            clear SOC_LUT                   
        end

        % Button pushed function: InitializeSimButton
        function InitializeSimButtonPushed(app, event)
            %Initialize simulation (starting the simulation AFTER 
            %starting the accumulator leads to a accumulator CAN Timeout.
            %Thus the simulation shall be initialized before the 
            %accumulator is started)
            open_system('validationtool');
            set_param('validationtool','SimulationCommand','start');
            set_param('validationtool','SimulationCommand','pause');
        end

        % Button pushed function: StartSimButton
        function StartSimButtonPushed(app, event)
            %Start simulation
            set_param('validationtool','SimulationCommand','continue');
        end

        % Button pushed function: StopSimButton
        function StopSimButtonPushed(app, event)
            %Stop simulation
            set_param('validationtool', 'SimulationCommand', 'stop');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'CTM_Logo_Final_4c_grun_ohne.png';
            app.BatterySimulatorControlPanel = uipanel(app.UIFigure);
            app.BatterySimulatorControlPanel.Title = 'Battery Simulator Control';
            app.OnButton = uibutton(app.BatterySimulatorControlPanel, 'push');
            app.OnButton.ButtonPushedFcn = createCallbackFcn(app, @OnButtonPushed, true);
            app.OnButton.Text = 'On';
            app.OffButton = uibutton(app.BatterySimulatorControlPanel, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Text = 'Off';
            app.ResetButton = uibutton(app.BatterySimulatorControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StatusBSLamp = uilamp(app.BatterySimulatorControlPanel);
            app.StatusEditFieldLabel = uilabel(app.BatterySimulatorControlPanel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.BatterySimulatorControlPanel, 'numeric');
            app.GetStatusButton_2 = uibutton(app.BatterySimulatorControlPanel, 'push');
            app.GetStatusButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButton_2Pushed, true);
            app.GetStatusButton_2.Text = 'Get Status';
            app.ValidationPanel = uipanel(app.UIFigure);
            app.ValidationPanel.Title = 'Validation';
            app.ImportDataButton = uibutton(app.ValidationPanel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.InitializeSimButton = uibutton(app.ValidationPanel, 'push');
            app.InitializeSimButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeSimButtonPushed, true);
            app.InitializeSimButton.Text = 'Initialize Sim';
            app.StopSimButton = uibutton(app.ValidationPanel, 'push');
            app.StopSimButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimButtonPushed, true);
            app.StopSimButton.Text = 'Stop Sim';
            app.StartSimButton = uibutton(app.ValidationPanel, 'push');
            app.StartSimButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimButtonPushed, true);
            app.StartSimButton.Text = 'Start Sim';
            app.AccumulatorvalidationtoolV10Label = uilabel(app.UIFigure);
            app.AccumulatorvalidationtoolV10Label.Text = 'Accumulatorvalidationtool V1.0';
            app.AccumulatorControlPanel = uipanel(app.UIFigure);
            app.AccumulatorControlPanel.Title = 'Accumulator Control';
            app.InitializeButton = uibutton(app.AccumulatorControlPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.OnButton_2 = uibutton(app.AccumulatorControlPanel, 'push');
            app.OnButton_2.ButtonPushedFcn = createCallbackFcn(app, @OnButton_2Pushed, true);
            app.OnButton_2.Text = 'On';
            app.OffButton_2 = uibutton(app.AccumulatorControlPanel, 'push');
            app.OffButton_2.ButtonPushedFcn = createCallbackFcn(app, @OffButton_2Pushed, true);
            app.OffButton_2.Text = 'Off';
            app.AMSOKLamp = uilamp(app.AccumulatorControlPanel);
            app.AMSOKEditFieldLabel = uilabel(app.AccumulatorControlPanel);
            app.AMSOKEditFieldLabel.Text = 'AMS OK';
            app.AMSOKEditField = uieditfield(app.AccumulatorControlPanel, 'numeric');
            app.SDCClosedLamp = uilamp(app.AccumulatorControlPanel);
            app.SDCClosedEditFieldLabel = uilabel(app.AccumulatorControlPanel);
            app.SDCClosedEditFieldLabel.Text = 'SDC Closed';
            app.SDCClosedEditField = uieditfield(app.AccumulatorControlPanel, 'numeric');
            app.GetStatusButton = uibutton(app.AccumulatorControlPanel, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlpanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end